classdef AplikacjaSterujaca < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        UIAxes             matlab.ui.control.UIAxes
        XEditFieldLabel    matlab.ui.control.Label
        XEditField         matlab.ui.control.NumericEditField
        WylijrozkazButton  matlab.ui.control.Button
        InicjalizujButton  matlab.ui.control.Button
        RysujkkaButton     matlab.ui.control.Button
        ZamknijportButton  matlab.ui.control.Button
        YEditFieldLabel    matlab.ui.control.Label
        YEditField         matlab.ui.control.NumericEditField
        ZEditFieldLabel    matlab.ui.control.Label
        ZEditField         matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        s % Description
        CzySkalibrowana  = 0 % Description
    end
    

    methods (Access = private)

        % Button pushed function: InicjalizujButton
        function InicjalizujButtonPushed(app, event)
            delete(instrfind);
            app.s = serial('COM4');
            set(app.s,'BaudRate',115200);
            fopen(app.s);
            pause(1)
            fprintf(app.s,'init');
            Koniec = 0;
            while Koniec == 0
                Lancuch = fread(app.s,1);
                if Lancuch == 107
                    Koniec = 1;
                end
            end
            app.CzySkalibrowana = 1;
        end

        % Button pushed function: ZamknijportButton
        function ZamknijportButtonPushed(app, event)
            fclose(app.s);
        end

        % Button pushed function: WylijrozkazButton
        function WylijrozkazButtonPushed(app, event)
            %             if isprop(app.s,'Status') && app.s.Status ~= 'open'
            %                 return
            %             end
            
            X = int32(app.XEditField.Value);
            Y = int32(app.YEditField.Value);
            Z = int32(app.ZEditField.Value);
            
            tekst = ['x ' num2str(X) ' y ' num2str(Y) ' z ' num2str(Z)];
            
            fprintf(app.s, tekst);
            while app.s.BytesAvailable==0
            end
            a = fscanf(app.s,'%s');
            
            app.CzySkalibrowana = 0;
        end

        % Button pushed function: RysujkkaButton
        function RysujkkaButtonPushed(app, event)
            x1 = 2;
            y1 = 0;
            z1 = 0;
            x2 = 8;
            y2 = 0;
            z2 = 0;
            x3 = 5;
            y3 = 3*sqrt(3);
            z3 = 0;
            xc = 5;
            yc = sqrt(3);
            zc = 0;
            r = 7;
            
            z1 = zc + sqrt(r^2 - (x1 - xc)^2 - (y1 - yc)^2);
            z2 = zc + sqrt(r^2 - (x2 - xc)^2 - (y2 - yc)^2);
            z3 = zc + sqrt(r^2 - (x3 - xc)^2 - (y3 - yc)^2);
            
            cla(app.UIAxes,'reset');
            hold(app.UIAxes,'on');
            col1 = line(app.UIAxes,[x1 x1], [y1 y1], [0 10]);
            col2 = line(app.UIAxes,[x2 x2], [y2 y2], [0 10]);
            col3 = line(app.UIAxes,[x3 x3], [y3 y3], [0 10]);
            arm1 = line(app.UIAxes,[x1 xc], [y1 yc], [z1 zc]);
            set(arm1, 'XData',[x1 xc],...
                'YData',[y1 yc],...
                'ZData',[z1 zc]);
            arm2 = line(app.UIAxes,[x2 xc], [y2 yc], [z2 zc]);
            set(arm2, 'XData',[x2 xc],...
                'YData',[y2 yc],...
                'ZData',[z2 zc]);
            arm3 = line(app.UIAxes,[x3 xc], [y3 yc], [z3 zc]);
            set(arm3, 'XData',[x3 xc],...
                'YData',[y3 yc],...
                'ZData',[z3 zc]);
            app.UIAxes.XLim = [x1,x2];
            app.UIAxes.YLim = [y1,y3];
            pause(1);
            
            if app.CzySkalibrowana == 0
                app.InicjalizujButtonPushed();
            end
            
            for t=0:0.05:20
                xc1 = xc + 6 * (0.1*sin(t));
                yc1 = yc + 6 * (0.1*cos(t));
                zc = 2;
                z1 = zc + sqrt(r^2 - (x1 - xc1)^2 - (y1 - yc1)^2);
                z2 = zc + sqrt(r^2 - (x2 - xc1)^2 - (y2 - yc1)^2);
                z3 = zc + sqrt(r^2 - (x3 - xc1)^2 - (y3 - yc1)^2);
                set(arm1, 'XData',[x1 xc1],...
                    'YData',[y1 yc1],...
                    'ZData',[z1 zc]);
                set(arm2, 'XData',[x2 xc1],...
                    'YData',[y2 yc1],...
                    'ZData',[z2 zc]);
                set(arm3, 'XData',[x3 xc1],...
                    'YData',[y3 yc1],...
                    'ZData',[z3 zc]);
                
                if exist('z01', 'var')
                    kroki1 = z1 - z01;
                    kroki2 = z2 - z02;
                    kroki3 = z3 - z03;
                end
                
                plot3(app.UIAxes, xc1, yc1, zc, '.')
                
                z01 = z1;
                z02 = z2;
                z03 = z3;
                
                if exist('kroki1', 'var')
                    tekst = ['x ' num2str(round(kroki1*1000))...
                        ' y ' num2str(round(kroki2*1000))...
                        ' z ' num2str(round(kroki3*1000))];
                    fprintf(app.s, tekst);
                    while app.s.BytesAvailable==0
                    end
                    a = fscanf(app.s,'%s');
                end
                drawnow();
            end
            app.CzySkalibrowana = 0;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Wizualizacja')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.WylijrozkazButton = uibutton(app.UIFigure, 'push');
            app.WylijrozkazButton.ButtonPushedFcn = createCallbackFcn(app, @WylijrozkazButtonPushed, true);
            app.WylijrozkazButton.Text = 'Wyślij rozkaz';
            app.InicjalizujButton = uibutton(app.UIFigure, 'push');
            app.InicjalizujButton.ButtonPushedFcn = createCallbackFcn(app, @InicjalizujButtonPushed, true);
            app.InicjalizujButton.Text = 'Inicjalizuj';
            app.RysujkkaButton = uibutton(app.UIFigure, 'push');
            app.RysujkkaButton.ButtonPushedFcn = createCallbackFcn(app, @RysujkkaButtonPushed, true);
            app.RysujkkaButton.Text = 'Rysuj kółka';
            app.ZamknijportButton = uibutton(app.UIFigure, 'push');
            app.ZamknijportButton.ButtonPushedFcn = createCallbackFcn(app, @ZamknijportButtonPushed, true);
            app.ZamknijportButton.Text = 'Zamknij port';
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = AplikacjaSterujaca

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end